classdef TrackingTaskGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FrameRateDropDown            matlab.ui.control.DropDown
        FrameRateDropDownLabel       matlab.ui.control.Label
        PathDropDown                 matlab.ui.control.DropDown
        PathDropDownLabel            matlab.ui.control.Label
        ReadyLamp                    matlab.ui.control.Lamp
        ReadyLampLabel               matlab.ui.control.Label
        TargetPathLabel              matlab.ui.control.Label
        TrackingTask10Label          matlab.ui.control.Label
        BeginButton                  matlab.ui.control.Button
        FrameRateSelectionLabel      matlab.ui.control.Label
        blockNum                     matlab.ui.control.EditField
        BlockEditFieldLabel          matlab.ui.control.Label
        subjectNum                   matlab.ui.control.EditField
        SubjectEditFieldLabel        matlab.ui.control.Label
        ParticipantInformationLabel  matlab.ui.control.Label
    end

    
    methods (Access = private)

        function TrackingTask(app)
            %PTB Setup
            Screen('Preference', 'ConserveVRAM', 4096);

            Screen('Preference', 'SkipSyncTests', 0); %Setting to skip synching test, 0-to enable, 1-to skip
            Screen('Preference', 'VisualDebugLevel', 0);
            KbName('UnifyKeyNames');
            PsychDefaultSetup(2);

            screenID = max(Screen('Screens')); %Retrieve screen ID of the secondary display.

            SetResolution(screenID, 1920, 1080, 480);
            % [win, rect] = PsychImaging('OpenWindow', screenID, [0 0 0]);% Opens default PTB window on desired display with a black background
            % Open the window with the fast-clearing engine fix (the '2' at the end)
            
            % =========================================================================
            % THE ENGINE FIX: Initialize and configure the modern imaging pipeline
            % =========================================================================
            PsychImaging('PrepareConfiguration');

            % This safely activates the ultra-high-speed rendering pipeline by name
            PsychImaging('AddTask', 'General', 'UseFastGis');

            % Open the window using PsychImaging instead of Screen('OpenWindow')
            [win, rect] = PsychImaging('OpenWindow', screenID, [0 0 0]);

            Screen('BlendFunction', win, GL_SRC_ALPHA, GL_ONE_MINUS_SRC_ALPHA);%Anti-aliasing and transparency
            ifi = Screen('GetFlipInterval', win); %Inter Frame Interval. Measures exact duration of a single frame on monitor in seconds.
            [widthMM, heightMM] = Screen('DisplaySize', screenID);

            [targetImg,~,targetAlpha] = imread('food_0001_sphere_300.png');
            if ~isempty(targetAlpha)
                targetImg(:,:,4) = targetAlpha;
            end
            targetTexture = Screen('MakeTexture', win, targetImg);
            [imgHeight,imgWidth,~] = size(targetImg);
            imgAspect = imgWidth/imgHeight;
            destRect = [100 100 500 500]; % [x1 y1 x2 y2]

            %Start Screen
            startText = ['Welcome to the Tracking Task \n\n' ...
                'Move the mouse to control the cross-hair\n'...
                'Track the dot and attempt to keep it in the white rectangular borders of the crosshair \n\n' ...
                'Press Space to begin']; %Instructional text
            Screen('TextSize',win,40);
            Screen('TextFont',win,'Arial');
            DrawFormattedText(win,startText,'center','center',[0,1,0]); %Draws text on screen in green
            Screen('Flip',win);

            %Wait for space-bar press to start program
            KbReleaseWait;
            while true
                [keyIsDown,~,keyCode] = KbCheck;
                if keyIsDown && keyCode(KbName('SPACE'))
                    break
                end
            end
            KbReleaseWait;

            %---------------Experimental Section-----------------------------


            %Random Motion Path for Target
            appFolder = fileparts(mfilename('fullpath'));

            motionFolder = fullfile(appFolder,'10 Random Motions');

            rmp = readtable(fullfile(motionFolder,sprintf('rmp_%s',app.PathDropDown.Value)));
            [xTarget,yTarget] = randomMotion(app,rmp);


            [xCenter, yCenter] = RectCenter(rect); %define the center coordinates of the screen based on screen dimensions
            %Hide the cursor and set the control to the center of the screen
            HideCursor;
            SetMouse(xCenter, yCenter, win);
            Screen('Flip', win);

            %Frame Rate Properties

            frameRate = str2double(app.FrameRateDropDown.Value);

            frameDuration = 1/frameRate;

            %Camera Properties
            cameraX = 0;
            cameraY = 0;
            sensitivity = 30;

            %Target Properties
            targetX = xCenter;      % starting X position
            targetY = yCenter;      % starting Y position
            targetSize = 20;
            targetColor = [255 0 0];
            % motion parameters
            speed = 200;                     % pixels per frame
            direction = rand * 2*pi;       % random initial direction
            changeProb = 1;             % probability per frame to change direction

            % Background Dots Properties
            b_dotSize = 5;
            b_dotColor = [0,1,0];
            numBgDots = 10000;
            % Global Boundaries
            worldMin = -20000;
            worldMax =  20000;
            bgDotsX = worldMin + (worldMax - worldMin) * rand(numBgDots, 1);
            bgDotsY = worldMin + (worldMax - worldMin) * rand(numBgDots, 1);
            bgDotsZ = worldMin + (worldMax - worldMin) * rand(numBgDots, 1);


            %background dot density calculation
            mmPerPixel = mean([widthMM/rect(3), heightMM/rect(4)]);
            mmPerPixelSq = mmPerPixel^2; %area of one pixel

            worldArea = (worldMax - worldMin)^2;          % total world area (px^2, world units)
            screenAreaPx = rect(3) * rect(4);             % screen area in pixels^2
            screenAreaMM2 = screenAreaPx * mmPerPixelSq;  % screen area in mm^2

            expectedVisibleDots = numBgDots * (screenAreaPx / worldArea);
            dotDensity_mm2 = expectedVisibleDots / screenAreaMM2;

            % Cross-hair Properties
            crsHr_width = 300;
            crsHr_height = 100;
            crsHr_color = [255,255,255];
            crsHr_thickness = 4;


            %Depth Properties
            cameraZ = 0;
            depthStep = 5;
            targetZ = 500;
            refDistance = 800;
            targetBaseSize = targetSize;
            minTargetSize = 4;
            maxTargetSize = 150;
            vbl = Screen('Flip', win);

            motionFPSBuffer = [];
            avgMotionFPS = 0;
            motionInterval = NaN;
            flipInterval = NaN;
            missed = NaN;

            %Frame Skipping Variables
            lastMotionTime = GetSecs; %Time stamp of last frame flip
            screenFlips = []; %Array of last 20 flip timestamps
            avgFPS_20 = 0; %avg from screenFlips
            framesPerUpdate = max(1,round((frameDuration)/ifi)); %number of frames per flip
            frameCounter = 0;
            motionCounter = 1;

            joy = vrjoystick(1);   % 1 = first joystick detected

            % Setup experimental screen
            % data = struct('SubjectNum',[],'blockNum',[],'frameRate',[],'TimeStamp',[],'onTarget',[],'Txi',[],'Tyi',[],'Tzi',[],'Cxi',[],'Cyi',[],'Czi',[]);
            data = struct([]);

            visibleDots = [xCenter; yCenter];   % dummy dot so variable always exists
            visibleSizes = 1;
            

            %MAIN LOOP
            while true

                %Display Expected frame rate
                expectedFrameRate = sprintf('Expected Frame Rate = %d FPS \n',frameRate);
                Screen('TextSize',win,20);
                Screen('TextFont',win,'Arial');
                DrawFormattedText(win,expectedFrameRate,rect(3)-700,30,[255,255,255]);

                %Display Actual Frame Rate
                RealFPS = sprintf('Real Frame Rate = %.2f FPS \n',avgMotionFPS);
                Screen('TextSize',win,20);
                Screen('TextFont',win,'Arial');
                DrawFormattedText(win,RealFPS,rect(3)-300,30,[255,255,255]);

                %Continously check for 'esc' press to exit program
                [keyIsDown, ~, keyCode] = KbCheck;
                if keyIsDown && keyCode(KbName('ESCAPE'))
                    break;
                end


                currentMotionTime = GetSecs;

                motionInterval = currentMotionTime - lastMotionTime;
                lastMotionTime = currentMotionTime;

                if motionInterval > 0
                    instantMotionFPS = 1/motionInterval;
                    motionFPSBuffer(end+1) = instantMotionFPS;

                    if numel(motionFPSBuffer) > 20
                        motionFPSBuffer(1) = [];
                    end
                    avgMotionFPS = mean(motionFPSBuffer);
                end
                pathSampleRate = 480;
                pathStep = round(pathSampleRate / frameRate);

                motionCounter = motionCounter + pathStep;
                motionCounter = min(motionCounter, length(xTarget));


                % Read all axes and buttons
                [axes, buttons, pov] = read(joy);

                % Typical Warthog mapping:
                jx = axes(1);   % X axis (left/right)
                jy = axes(2);   % Y axis (forward/back)

                % Deadzone
                deadzone = 0.03;
                if abs(jx) < deadzone, jx = 0; end
                if abs(jy) < deadzone, jy = 0; end

                % Apply movement
                cameraX = cameraX + jx * sensitivity;
                cameraY = cameraY + jy * sensitivity;

                [~,~,keyCode] = KbCheck;
                if keyCode(KbName('upArrow'))
                    cameraZ = cameraZ + depthStep;
                elseif keyCode(KbName('downArrow'))
                    cameraZ = cameraZ - depthStep;
                end

                distance = targetZ - cameraZ;
                distance = max(distance,1);
                apparentSize = targetBaseSize * (refDistance/distance);
                apparentSize = min(max(apparentSize, minTargetSize), maxTargetSize);

                %Convert background dots to screen coordinates
                relX = bgDotsX - cameraX;
                relY = bgDotsY - cameraY;
                relZ = bgDotsZ - cameraZ;
                relZ = max(relZ,1);

                scale = refDistance ./ relZ;

                screen_b_DotsX = xCenter + relX .* scale;
                screen_b_DotsY = yCenter + relY .* scale;

                dotSizes = b_dotSize .* scale;
                dotSizes = max(dotSizes, 1);          % prevent too-small dots
                dotSizes = min(dotSizes, 150);        % optional: prevent giant dots


                %Only draw background dots that are on-screen to optimize
                %performance
                onScreen = screen_b_DotsX > 0 & screen_b_DotsX < rect(3) & screen_b_DotsY > 0 & screen_b_DotsY < rect(4); %background dots must stay within the x and y boundaries of the screen
                visibleDots = [screen_b_DotsX(onScreen)'; screen_b_DotsY(onScreen)'];
                visibleSizes = dotSizes(onScreen);

                %Draw target
                dtMotion = framesPerUpdate*ifi; %delta motion described by time in seconds for the number of frames drawn
                screen_targetX = xTarget(motionCounter) - cameraX;
                screen_targetY = yTarget(motionCounter) - cameraY;

                destWidth = apparentSize * 2;
                destHeight = destWidth/imgAspect;
                destRect = CenterRectOnPointd([0 0 destWidth destHeight], screen_targetX, screen_targetY);


                Screen('DrawDots', win, visibleDots, visibleSizes, b_dotColor, [], 2); % Draw background dots


                Screen('DrawTexture', win, targetTexture, [], destRect);

                %Draw crosshair
                DrawCrossHair(app,crsHr_height,crsHr_height,crsHr_color, crsHr_thickness,xCenter,yCenter,win);


                idx = numel(data) + 1;

                data(idx).SubjectNum = app.subjectNum.Value;
                data(idx).blockNum   = app.blockNum.Value;
                data(idx).frameRate  = frameRate;

                data(idx).TimeStamp = vbl;
                data(idx).Txi = screen_targetX;
                data(idx).Tyi = screen_targetY;
                data(idx).Tzi = distance;

                data(idx).Cxi = cameraX;
                data(idx).Cyi = cameraY;
                data(idx).Czi = cameraZ;

                
                previousVbl = vbl;

                [vbl,stimulusOnset,flipEnd,missed] = Screen('Flip',win,previousVbl+frameDuration-0.5*ifi);


                % vbl = Screen('Flip', win);
                flipInterval = vbl-previousVbl;


            end

            T = struct2table(data);
            writetable(T, 'output_data.csv');
            Screen('Close', targetTexture);
            sca;

        end

        function DrawCrossHair(~,width, height, color, thickness, xCenter, yCenter, win)
            halfW = width/2;
            halfH = height/2;

            % How far the corner brackets extend along each edge (tune this ratio)
            bracketLenX = halfW * 0.6;
            bracketLenY = halfH * 0.6;

            % How far the crosshair lines extend beyond the square
            lineExtentX = halfW * 1.4;
            lineExtentY = halfH * 1.4;

            % --- Full horizontal line (passes through, extends past square) ---
            Screen('DrawLine', win, color, ...
                xCenter - lineExtentX, yCenter, ...
                xCenter + lineExtentX, yCenter, thickness);

            % --- Full vertical line (passes through, extends past square) ---
            Screen('DrawLine', win, color, ...
                xCenter, yCenter - lineExtentY, ...
                xCenter, yCenter + lineExtentY, thickness);

            % --- Top-left bracket ---
            Screen('DrawLine', win, color, xCenter - halfW, yCenter - halfH, xCenter - halfW + bracketLenX, yCenter - halfH, thickness); % top edge
            Screen('DrawLine', win, color, xCenter - halfW, yCenter - halfH, xCenter - halfW, yCenter - halfH + bracketLenY, thickness); % left edge

            % --- Top-right bracket ---
            Screen('DrawLine', win, color, xCenter + halfW, yCenter - halfH, xCenter + halfW - bracketLenX, yCenter - halfH, thickness);
            Screen('DrawLine', win, color, xCenter + halfW, yCenter - halfH, xCenter + halfW, yCenter - halfH + bracketLenY, thickness);

            % --- Bottom-left bracket ---
            Screen('DrawLine', win, color, xCenter - halfW, yCenter + halfH, xCenter - halfW + bracketLenX, yCenter + halfH, thickness);
            Screen('DrawLine', win, color, xCenter - halfW, yCenter + halfH, xCenter - halfW, yCenter + halfH - bracketLenY, thickness);

            % --- Bottom-right bracket ---
            Screen('DrawLine', win, color, xCenter + halfW, yCenter + halfH, xCenter + halfW - bracketLenX, yCenter + halfH, thickness);
            Screen('DrawLine', win, color, xCenter + halfW, yCenter + halfH, xCenter + halfW, yCenter + halfH - bracketLenY, thickness);
        end

        function [x,y] = randomMotion(~,motionFile)
            x = motionFile.X*1000;
            y = motionFile.Y*1000;
        end

        function isOnTarget = checkTarget(~,targetX,targetY,xCenter,yCenter,crsHairHeight,crsHairWidth)
            withinX = targetX < xCenter + crsHairWidth/2 && targetX > xCenter - crsHairWidth/2;
            withinY = targetY < yCenter + crsHairHeight/2 && targetY > yCenter - crsHairHeight/2;
            if withinX && withinY
                isOnTarget = true;
                elsemo
                isOnTarget = false;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BeginButton
        function BeginButtonPushed(app, event)
            TrackingTask(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ParticipantInformationLabel = uilabel(app.UIFigure);
            app.ParticipantInformationLabel.Text = 'Participant Information';
            app.SubjectEditFieldLabel = uilabel(app.UIFigure);
            app.SubjectEditFieldLabel.Text = 'Subject #';
            app.subjectNum = uieditfield(app.UIFigure, 'text');
            app.BlockEditFieldLabel = uilabel(app.UIFigure);
            app.BlockEditFieldLabel.Text = 'Block #';
            app.blockNum = uieditfield(app.UIFigure, 'text');
            app.FrameRateSelectionLabel = uilabel(app.UIFigure);
            app.FrameRateSelectionLabel.Text = 'Frame Rate Selection';
            app.BeginButton = uibutton(app.UIFigure, 'push');
            app.BeginButton.ButtonPushedFcn = createCallbackFcn(app, @BeginButtonPushed, true);
            app.BeginButton.Text = 'Begin';
            app.TrackingTask10Label = uilabel(app.UIFigure);
            app.TrackingTask10Label.Text = 'Tracking Task 1.0';
            app.TargetPathLabel = uilabel(app.UIFigure);
            app.TargetPathLabel.Text = 'Target Path';
            app.ReadyLampLabel = uilabel(app.UIFigure);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.UIFigure);
            app.PathDropDownLabel = uilabel(app.UIFigure);
            app.PathDropDownLabel.Text = 'Path';
            app.PathDropDown = uidropdown(app.UIFigure);
            app.PathDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '9', '10'};
            app.PathDropDown.Value = '1';
            app.FrameRateDropDownLabel = uilabel(app.UIFigure);
            app.FrameRateDropDownLabel.Text = 'Frame Rate';
            app.FrameRateDropDown = uidropdown(app.UIFigure);
            app.FrameRateDropDown.Items = {'60', '120', '240', '280'};
            app.FrameRateDropDown.Value = '60';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrackingTaskGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end